classdef ExpresswayArterialSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SilulationSettingPanel      matlab.ui.container.Panel
        ShowGapEditField            matlab.ui.control.NumericEditField
        ShowGapEditFieldLabel       matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        StartSimulationButton       matlab.ui.control.Button
        EndTimeEditField            matlab.ui.control.EditField
        EndTimeEditFieldLabel       matlab.ui.control.Label
        StartTimeEditField          matlab.ui.control.EditField
        StartTimeEditFieldLabel     matlab.ui.control.Label
        FlowSettingPanel            matlab.ui.container.Panel
        ShowFlowButton              matlab.ui.control.Button
        CAVMPREditField             matlab.ui.control.NumericEditField
        CAVMPREditFieldLabel        matlab.ui.control.Label
        BasicFlowEditField          matlab.ui.control.NumericEditField
        BasicFlowEditFieldLabel     matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        RoadSettingsPanel           matlab.ui.container.Panel
        AddMainButton               matlab.ui.control.Button
        ClearRoadButton             matlab.ui.control.Button
        AddOffButton                matlab.ui.control.Button
        AddOnButton                 matlab.ui.control.Button
        OffRampEndEditField         matlab.ui.control.NumericEditField
        OffRampEndEditFieldLabel    matlab.ui.control.Label
        OffRampStartEditField       matlab.ui.control.NumericEditField
        OffRampStartEditFieldLabel  matlab.ui.control.Label
        OnRampEndEditField          matlab.ui.control.NumericEditField
        OnRampEndEditFieldLabel     matlab.ui.control.Label
        OnRampStartEditField        matlab.ui.control.NumericEditField
        OnRampStartEditFieldLabel   matlab.ui.control.Label
        CellLengthEditField         matlab.ui.control.NumericEditField
        CellLengthEditFieldLabel    matlab.ui.control.Label
        RoadLengthEditField         matlab.ui.control.NumericEditField
        RoadLengthEditFieldLabel    matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        SimulationResultsPanel      matlab.ui.container.Panel
        CO2EditField_2Label         matlab.ui.control.Label
        CO2EditField_2              matlab.ui.control.NumericEditField
        NOxEditField_2              matlab.ui.control.NumericEditField
        NOxEditField_2Label         matlab.ui.control.Label
        HCEditField_2               matlab.ui.control.NumericEditField
        HCEditField_2Label          matlab.ui.control.Label
        COEditField_2               matlab.ui.control.NumericEditField
        COEditField_2Label          matlab.ui.control.Label
        FuelEditField_2             matlab.ui.control.NumericEditField
        FuelEditField_2Label        matlab.ui.control.Label
        CO2EditField                matlab.ui.control.NumericEditField
        CO2EditFieldLabel           matlab.ui.control.Label
        NOxEditField                matlab.ui.control.NumericEditField
        NOxEditFieldLabel           matlab.ui.control.Label
        HCEditField                 matlab.ui.control.NumericEditField
        HCEditFieldLabel            matlab.ui.control.Label
        COEditField                 matlab.ui.control.NumericEditField
        COEditFieldLabel            matlab.ui.control.Label
        FuelEditField               matlab.ui.control.NumericEditField
        FuelEditFieldLabel          matlab.ui.control.Label
        HistoryLabel                matlab.ui.control.Label
        CurrentLabel                matlab.ui.control.Label
        VisualizationPanel          matlab.ui.container.Panel
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        %公共变量，用app.调用
        road;
        roadcelllength;
        roadcellstart;
        roadcellend;
        roadcellgap;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 获取屏幕大小
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            % 获取App窗口大小
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            % 计算窗口在屏幕中央的位置
            xPosition = (screenWidth - appWidth) / 2;
            yPosition = (screenHeight - appHeight) / 2;
            % 设置窗口位置
            app.UIFigure.Position(1) = xPosition;
            app.UIFigure.Position(2) = yPosition;

            app.road = generatesegment(0, app.RoadLengthEditField.Value, app.CellLengthEditField.Value);
            app.roadcelllength = sum([app.road.Lane] == 1);
            app.roadcellstart = min([app.road.Position]);
            app.roadcellend = max([app.road.Position]);
            app.roadcellgap = (app.roadcellend - app.roadcellstart) / (app.roadcelllength - 1);
            app.road = generateonramp(app.road, app.OnRampStartEditField.Value, app.OnRampEndEditField.Value, app.roadcellgap);
            app.road = generateofframp(app.road, app.OffRampStartEditField.Value, app.OffRampEndEditField.Value, app.roadcellgap);
            show_road(app.road, [], app.roadcellstart, app.roadcellend, [], app.UIAxes)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.FuelEditField_2.Value=app.FuelEditField.Value;
            app.CO2EditField_2.Value=app.CO2EditField.Value;
            app.COEditField_2.Value=app.COEditField.Value;
            app.HCEditField_2.Value=app.HCEditField.Value;
            app.NOxEditField_2.Value=app.NOxEditField.Value;
            app.FuelEditField.Value=0;
            app.CO2EditField.Value=0;
            app.COEditField.Value=0;
            app.HCEditField.Value=0;
            app.NOxEditField.Value=0;

            basicflow=app.BasicFlowEditField.Value;
            CAVMPR=app.CAVMPREditField.Value;
            timeStr_start=app.StartTimeEditField.Value;
            timeDT_start=datetime(timeStr_start, 'InputFormat', 'HH:mm');
            Minutes_start=hour(timeDT_start) * 60 + minute(timeDT_start)+1;
            timeStr_end=app.EndTimeEditField.Value;
            timeDT_end=datetime(timeStr_end, 'InputFormat', 'HH:mm');
            Minutes_end=hour(timeDT_end) * 60 + minute(timeDT_end)+1;
            ShowGap=app.ShowGapEditField.Value;

            app.Lamp.Color='r';
            pause(0.01)
            [Fuel_coRate,CO2_emRate,CO_emRate,HC_emRate,NOx_emRate]=flowsimulate(app.road, app.roadcelllength, app.roadcellstart, app.roadcellend, app.roadcellgap,basicflow,CAVMPR,Minutes_start,Minutes_end,ShowGap,app.UIAxes, app.UIAxes_2, app.UIAxes_3);
            app.Lamp.Color='g';
            app.FuelEditField.Value=sum(Fuel_coRate);
            app.CO2EditField.Value=sum(CO2_emRate);
            app.COEditField.Value=sum(CO_emRate);
            app.HCEditField.Value=sum(HC_emRate);
            app.NOxEditField.Value=sum(NOx_emRate);           
        end

        % Button pushed function: ShowFlowButton
        function ShowFlowButtonPushed(app, event)
            basicflow = app.BasicFlowEditField.Value;
            CAVMPR = app.CAVMPREditField.Value;
            load('BasicData.mat', 'flowrate', 'hourmintime')
            cla(app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, convertTo(hourmintime, 'datenum'), flowrate * basicflow * (1 - CAVMPR), 'b-')
            plot(app.UIAxes2, convertTo(hourmintime, 'datenum'), flowrate * basicflow * CAVMPR, 'r--')
            % 设置 xtick 数量
            numXTicks = 9; % 你想要的 xtick 数量
            % 获取当前 x 轴的范围
            xLimits = xlim(app.UIAxes2);
            % 设置固定数量的 xtick
            xTicks = linspace(xLimits(1), xLimits(2), numXTicks);
            app.UIAxes2.XTick = xTicks;
            % 应用 datetick 格式
            datetick(app.UIAxes2, 'x', 'HH:MM', 'keepticks', 'keeplimits')
            % 设置 xtick 文字大小
            app.UIAxes2.XAxis.FontSize = 8; % 设置你想要的字体大小
            grid(app.UIAxes2, 'on')
            legend(app.UIAxes2,'HV','CAV','Location','Best','FontSize',5)
            hold(app.UIAxes2, 'off')
        end

        % Button pushed function: AddOnButton
        function AddOnButtonPushed(app, event)
            cla(app.UIAxes)
            app.road = generateonramp(app.road, app.OnRampStartEditField.Value, app.OnRampEndEditField.Value, app.roadcellgap);
            show_road(app.road, [], app.roadcellstart, app.roadcellend, [], app.UIAxes)
        end

        % Button pushed function: AddOffButton
        function AddOffButtonPushed(app, event)
            cla(app.UIAxes)
            app.road = generateofframp(app.road, app.OffRampStartEditField.Value, app.OffRampEndEditField.Value, app.roadcellgap);
            show_road(app.road, [], app.roadcellstart, app.roadcellend, [], app.UIAxes)
        end

        % Button pushed function: ClearRoadButton
        function ClearRoadButtonPushed(app, event)
            cla(app.UIAxes)
            app.road = [];
        end

        % Button pushed function: AddMainButton
        function AddMainButtonPushed(app, event)
            app.road = generatesegment(0, app.RoadLengthEditField.Value, app.CellLengthEditField.Value);
            app.roadcelllength = sum([app.road.Lane] == 1);
            app.roadcellstart = min([app.road.Position]);
            app.roadcellend = max([app.road.Position]);
            app.roadcellgap = (app.roadcellend - app.roadcellstart) / (app.roadcelllength - 1);
            show_road(app.road, [], app.roadcellstart, app.roadcellend, [], app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RoadSettingsPanel = uipanel(app.LeftPanel);
            app.RoadSettingsPanel.Title = 'Road Settings';
            app.RoadLengthEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.RoadLengthEditFieldLabel.Text = 'Road Length';
            app.RoadLengthEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.RoadLengthEditField.ValueDisplayFormat = '%.0f';
            app.RoadLengthEditField.Value = 10000;
            app.CellLengthEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.CellLengthEditFieldLabel.Text = 'Cell Length';
            app.CellLengthEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.CellLengthEditField.ValueDisplayFormat = '%.0f';
            app.CellLengthEditField.Value = 200;
            app.OnRampStartEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.OnRampStartEditFieldLabel.Text = 'On Ramp Start';
            app.OnRampStartEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.OnRampStartEditField.ValueDisplayFormat = '%.0f';
            app.OnRampStartEditField.Value = 1800;
            app.OnRampEndEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.OnRampEndEditFieldLabel.Text = 'On Ramp End';
            app.OnRampEndEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.OnRampEndEditField.ValueDisplayFormat = '%.0f';
            app.OnRampEndEditField.Value = 2200;
            app.OffRampStartEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.OffRampStartEditFieldLabel.Text = 'Off Ramp Start';
            app.OffRampStartEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.OffRampStartEditField.ValueDisplayFormat = '%.0f';
            app.OffRampStartEditField.Value = 3800;
            app.OffRampEndEditFieldLabel = uilabel(app.RoadSettingsPanel);
            app.OffRampEndEditFieldLabel.Text = 'Off Ramp End';
            app.OffRampEndEditField = uieditfield(app.RoadSettingsPanel, 'numeric');
            app.OffRampEndEditField.ValueDisplayFormat = '%.0f';
            app.OffRampEndEditField.Value = 4200;
            app.AddOnButton = uibutton(app.RoadSettingsPanel, 'push');
            app.AddOnButton.ButtonPushedFcn = createCallbackFcn(app, @AddOnButtonPushed, true);
            app.AddOnButton.Text = 'Add On';
            app.AddOffButton = uibutton(app.RoadSettingsPanel, 'push');
            app.AddOffButton.ButtonPushedFcn = createCallbackFcn(app, @AddOffButtonPushed, true);
            app.AddOffButton.Text = 'Add Off';
            app.ClearRoadButton = uibutton(app.RoadSettingsPanel, 'push');
            app.ClearRoadButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRoadButtonPushed, true);
            app.ClearRoadButton.Text = 'Clear Road';
            app.AddMainButton = uibutton(app.RoadSettingsPanel, 'push');
            app.AddMainButton.ButtonPushedFcn = createCallbackFcn(app, @AddMainButtonPushed, true);
            app.AddMainButton.Text = 'Add Main';
            app.FlowSettingPanel = uipanel(app.LeftPanel);
            app.FlowSettingPanel.Title = 'Flow Setting';
            app.UIAxes2 = uiaxes(app.FlowSettingPanel);
            title(app.UIAxes2, 'Flow-Time')
            zlabel(app.UIAxes2, 'Z')
            app.BasicFlowEditFieldLabel = uilabel(app.FlowSettingPanel);
            app.BasicFlowEditFieldLabel.Text = 'Basic Flow';
            app.BasicFlowEditField = uieditfield(app.FlowSettingPanel, 'numeric');
            app.BasicFlowEditField.Value = 300;
            app.CAVMPREditFieldLabel = uilabel(app.FlowSettingPanel);
            app.CAVMPREditFieldLabel.Text = 'CAVMPR';
            app.CAVMPREditField = uieditfield(app.FlowSettingPanel, 'numeric');
            app.CAVMPREditField.Value = 0.5;
            app.ShowFlowButton = uibutton(app.FlowSettingPanel, 'push');
            app.ShowFlowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFlowButtonPushed, true);
            app.ShowFlowButton.Text = 'Show Flow';
            app.SilulationSettingPanel = uipanel(app.LeftPanel);
            app.SilulationSettingPanel.Title = 'Silulation Setting';
            app.StartTimeEditFieldLabel = uilabel(app.SilulationSettingPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.SilulationSettingPanel, 'text');
            app.StartTimeEditField.Value = '00:00';
            app.EndTimeEditFieldLabel = uilabel(app.SilulationSettingPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.SilulationSettingPanel, 'text');
            app.EndTimeEditField.Value = '23:59';
            app.StartSimulationButton = uibutton(app.SilulationSettingPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.Lamp = uilamp(app.SilulationSettingPanel);
            app.ShowGapEditFieldLabel = uilabel(app.SilulationSettingPanel);
            app.ShowGapEditFieldLabel.Text = 'Show Gap';
            app.ShowGapEditField = uieditfield(app.SilulationSettingPanel, 'numeric');
            app.ShowGapEditField.Value = 30;
            app.RightPanel = uipanel(app.GridLayout);
            app.VisualizationPanel = uipanel(app.RightPanel);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.VisualizationPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.VisualizationPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SimulationResultsPanel = uipanel(app.RightPanel);
            app.SimulationResultsPanel.Title = 'Simulation Results';
            app.CurrentLabel = uilabel(app.SimulationResultsPanel);
            app.CurrentLabel.Text = 'Current:';
            app.HistoryLabel = uilabel(app.SimulationResultsPanel);
            app.HistoryLabel.Text = 'History:';
            app.FuelEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.FuelEditFieldLabel.Text = 'Fuel';
            app.FuelEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.FuelEditField.ValueDisplayFormat = '%.0f';
            app.COEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.COEditFieldLabel.Text = 'CO';
            app.COEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.COEditField.ValueDisplayFormat = '%.0f';
            app.HCEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.HCEditFieldLabel.Text = 'HC';
            app.HCEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.HCEditField.ValueDisplayFormat = '%.0f';
            app.NOxEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.NOxEditFieldLabel.Text = 'NOx';
            app.NOxEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.NOxEditField.ValueDisplayFormat = '%.0f';
            app.CO2EditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.CO2EditFieldLabel.Text = 'CO2';
            app.CO2EditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.CO2EditField.ValueDisplayFormat = '%.0f';
            app.FuelEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.FuelEditField_2Label.Text = 'Fuel';
            app.FuelEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.FuelEditField_2.ValueDisplayFormat = '%.0f';
            app.COEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.COEditField_2Label.Text = 'CO';
            app.COEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.COEditField_2.ValueDisplayFormat = '%.0f';
            app.HCEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.HCEditField_2Label.Text = 'HC';
            app.HCEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.HCEditField_2.ValueDisplayFormat = '%.0f';
            app.NOxEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.NOxEditField_2Label.Text = 'NOx';
            app.NOxEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.NOxEditField_2.ValueDisplayFormat = '%.0f';
            app.CO2EditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.CO2EditField_2.ValueDisplayFormat = '%.0f';
            app.CO2EditField_2Label = uilabel(app.SimulationResultsPanel);
            app.CO2EditField_2Label.Text = 'CO2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExpresswayArterialSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end