classdef expressway_network_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        StartAssignmentButton       matlab.ui.control.Button
        OutputsPanel                matlab.ui.container.Panel
        Lamp                        matlab.ui.control.Lamp
        TTTEditField                matlab.ui.control.NumericEditField
        TTTEditFieldLabel           matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        SettingsPanel               matlab.ui.container.Panel
        ShowDesireLineButton        matlab.ui.control.Button
        MaxIterEditField            matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel       matlab.ui.control.Label
        AVMPREditField              matlab.ui.control.NumericEditField
        AVMPREditFieldLabel         matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        DemandFactorEditField       matlab.ui.control.NumericEditField
        DemandFactorEditFieldLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        VisualizationPanel          matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            % 获取App窗口大小
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            % 计算窗口在屏幕中央的位置
            xPosition = (screenWidth - appWidth) / 2;
            yPosition = (screenHeight - appHeight) / 2;
            % 设置窗口位置
            app.UIFigure.Position(1) = xPosition;
            app.UIFigure.Position(2) = yPosition;
            load("basicdata.mat","OD_demand","OD_pair","node_char")
            app.UITable.Data=[OD_pair,OD_demand];
            app.UITable.ColumnWidth = {52,52,68};
            [img, ~]=imread('roadmap_wuhan_expressway.png');
            hold(app.UIAxes,"on")
            imshow(img,'Parent',app.UIAxes);
            %绘制节点
            for ii=1:size(node_char,1)
                plot(app.UIAxes,node_char(ii,1), node_char(ii,2), 'w.');
                text(app.UIAxes,node_char(ii,1), node_char(ii,2),join([' ',num2str(ii)]),Color='w')
            end
            hold(app.UIAxes,"off")
        end

        % Button pushed function: StartAssignmentButton
        function StartAssignmentButtonPushed(app, event)
            load("basicdata.mat","OD_demand","OD_pair","link_char","link_table","node_char")
            [img, ~]=imread('roadmap_wuhan_expressway.png');

            MaxIter_Total_Update=app.MaxIterEditField.Value;
            TTT_each_iter=zeros(1,MaxIter_Total_Update);

            % 搜寻路径
            incidence_matrix=double(link_table>0);
            [totalPaths,path_num]=findtotalPath(incidence_matrix,OD_pair(:,1),OD_pair(:,2),0); %寻找全部OD对的路径
            totalPaths=[totalPaths;totalPaths];
            path_num=[path_num;path_num];
            number_of_path = size(totalPaths,1);

            %设置OD
            AVMPR=app.AVMPREditField.Value;
            demand_factor=app.DemandFactorEditField.Value;
            OD_demand=OD_demand*demand_factor;
            OD_demand_HV=OD_demand*(1-AVMPR);
            OD_demand_AV=OD_demand*(AVMPR);
            OD_demand_HV_AV=[OD_demand_HV;OD_demand_AV];

            %设置pathflow的初始值pathflow0，为均分每一个OD
            path_flow0 = zeros(number_of_path,1);
            index_start = 1;
            for ii = 1:size(OD_demand_HV_AV,1)
                index_end = index_start + path_num(ii) - 1;
                path_flow0(index_start:index_end, 1) = OD_demand_HV_AV(ii) / path_num(ii);
                index_start = index_end + 1;
            end

            %第一次迭代
            maxiter_single_update=1;
            app.Lamp.Color='r';
            pause(0.01)
            [total_time,VC_ratio,path_flow]=fun_expressway_network_flow(link_char,link_table,OD_demand_HV_AV,OD_pair,path_flow0,maxiter_single_update,totalPaths,path_num);
            app.Lamp.Color='g';
            app.TTTEditField.Value=total_time;
            shownet(VC_ratio,link_table,link_char,node_char,app.UIAxes,img);
            title(app.UIAxes,join(['Iter=',num2str(0)]));

            grid(app.UIAxes2,"on")
            for ii=1:MaxIter_Total_Update
                app.Lamp.Color='r';
                pause(0.01)
                [total_time,VC_ratio,path_flow]=fun_expressway_network_flow(link_char,link_table,OD_demand_HV_AV,OD_pair,path_flow,maxiter_single_update,totalPaths,path_num);
                app.Lamp.Color='g';
                app.TTTEditField.Value=total_time;
                TTT_each_iter(ii)=total_time;
                plot(app.UIAxes2,1:ii,TTT_each_iter(1:ii));
                shownet(VC_ratio,link_table,link_char,node_char,app.UIAxes,img);
                title(app.UIAxes,join(['Iter=',num2str(ii)]));
            end
        end

        % Button pushed function: ShowDesireLineButton
        function ShowDesireLineButtonPushed(app, event)
            load("basicdata.mat","OD_demand","OD_pair","node_char")
            hold(app.UIAxes,"on")
            for ii=1:numel(OD_demand)
                plot(app.UIAxes,[node_char(OD_pair(ii,1),1),node_char(OD_pair(ii,2),1)],[node_char(OD_pair(ii,1),2),node_char(OD_pair(ii,2),2)],Color='c',LineWidth=5*OD_demand(ii)./max(OD_demand))
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {674, 674};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {223, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.DemandFactorEditFieldLabel = uilabel(app.SettingsPanel);
            app.DemandFactorEditFieldLabel.Text = 'Demand Factor';
            app.DemandFactorEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DemandFactorEditField.Value = 1;
            app.UITable = uitable(app.SettingsPanel);
            app.UITable.ColumnName = {'Onode'; 'Dnode'; 'Demand'; 'Column 4'};
            app.UITable.RowName = {};
            app.AVMPREditFieldLabel = uilabel(app.SettingsPanel);
            app.AVMPREditFieldLabel.Text = 'AVMPR';
            app.AVMPREditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AVMPREditField.Value = 0.5;
            app.MaxIterEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaxIterEditFieldLabel.Text = 'MaxIter';
            app.MaxIterEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MaxIterEditField.Value = 100;
            app.ShowDesireLineButton = uibutton(app.SettingsPanel, 'push');
            app.ShowDesireLineButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDesireLineButtonPushed, true);
            app.ShowDesireLineButton.Text = 'Show Desire Line';
            app.OutputsPanel = uipanel(app.LeftPanel);
            app.OutputsPanel.Title = 'Outputs';
            app.UIAxes2 = uiaxes(app.OutputsPanel);
            xlabel(app.UIAxes2, 'Iter')
            ylabel(app.UIAxes2, 'TTT')
            zlabel(app.UIAxes2, 'Z')
            app.TTTEditFieldLabel = uilabel(app.OutputsPanel);
            app.TTTEditFieldLabel.Text = 'TTT';
            app.TTTEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.TTTEditField.ValueDisplayFormat = '%.2f';
            app.Lamp = uilamp(app.OutputsPanel);
            app.StartAssignmentButton = uibutton(app.LeftPanel, 'push');
            app.StartAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartAssignmentButtonPushed, true);
            app.StartAssignmentButton.Text = 'Start Assignment';
            app.RightPanel = uipanel(app.GridLayout);
            app.VisualizationPanel = uipanel(app.RightPanel);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expressway_network_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end