classdef ExpresswayRampSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SimulationSettingsPanel         matlab.ui.container.Panel
        MaxVehiclePassedEditField       matlab.ui.control.NumericEditField
        MaxVehiclePassedEditFieldLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ShowSimLabel                    matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        hideButton                      matlab.ui.control.RadioButton
        showButton                      matlab.ui.control.RadioButton
        StartSimulationButton           matlab.ui.control.Button
        PreheatIterationsEditField      matlab.ui.control.NumericEditField
        PreheatIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        FlowSettingsPanel               matlab.ui.container.Panel
        AVMPREditField                  matlab.ui.control.NumericEditField
        AVMPREditFieldLabel             matlab.ui.control.Label
        Diverge_RateEditField           matlab.ui.control.NumericEditField
        Diverge_RateEditFieldLabel      matlab.ui.control.Label
        Merge_RateEditField             matlab.ui.control.NumericEditField
        Merge_RateEditFieldLabel        matlab.ui.control.Label
        NumVehiclesEditField            matlab.ui.control.NumericEditField
        NumVehiclesEditFieldLabel       matlab.ui.control.Label
        RoadsettingPanel                matlab.ui.container.Panel
        BufferZone_DLEndEditField       matlab.ui.control.NumericEditField
        BufferZone_DLEndEditFieldLabel  matlab.ui.control.Label
        BufferZone_DLStartEditField     matlab.ui.control.NumericEditField
        BufferZone_DLStartEditFieldLabel  matlab.ui.control.Label
        ShowRoadButton                  matlab.ui.control.Button
        DedicatedLane3EditField         matlab.ui.control.NumericEditField
        DedicatedLane3EditFieldLabel    matlab.ui.control.Label
        divergingAreaEditField_7        matlab.ui.control.NumericEditField
        DedicatedLane2EditField         matlab.ui.control.NumericEditField
        DedicatedLane2EditFieldLabel    matlab.ui.control.Label
        divergingAreaEditField_5        matlab.ui.control.NumericEditField
        DedicatedLane1EditField         matlab.ui.control.NumericEditField
        DedicatedLane1EditFieldLabel    matlab.ui.control.Label
        divergingAreaEditField_3        matlab.ui.control.NumericEditField
        divergingAreaEditField_2        matlab.ui.control.NumericEditField
        offrampPositionEditField_2      matlab.ui.control.NumericEditField
        mergingAreaEditField_2          matlab.ui.control.NumericEditField
        onrampPositionEditField_2       matlab.ui.control.NumericEditField
        DivergingAreaEditField          matlab.ui.control.NumericEditField
        DivergingAreaEditFieldLabel     matlab.ui.control.Label
        OfframpPositionEditField        matlab.ui.control.NumericEditField
        OfframpPositionEditFieldLabel   matlab.ui.control.Label
        MergingAreaEditField            matlab.ui.control.NumericEditField
        MergingAreaEditFieldLabel       matlab.ui.control.Label
        OnrampPositionEditField         matlab.ui.control.NumericEditField
        OnrampPositionEditFieldLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        VisualizationPanel              matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
        SimulationResultsPanel          matlab.ui.container.Panel
        HistoryLabel                    matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        ThroughputEditField_2           matlab.ui.control.NumericEditField
        ThroughputEditField_2Label      matlab.ui.control.Label
        AverageRiskEditField_2          matlab.ui.control.NumericEditField
        AverageRiskEditField_2Label     matlab.ui.control.Label
        TotalRiskEditField_2            matlab.ui.control.NumericEditField
        TotalRiskEditField_2Label       matlab.ui.control.Label
        VehiclePassedEditField_2        matlab.ui.control.NumericEditField
        VehiclePassedEditField_2Label   matlab.ui.control.Label
        ThroughputEditField             matlab.ui.control.NumericEditField
        ThroughputEditFieldLabel        matlab.ui.control.Label
        AverageRiskEditField            matlab.ui.control.NumericEditField
        AverageRiskEditFieldLabel       matlab.ui.control.Label
        TotalRiskEditField              matlab.ui.control.NumericEditField
        TotalRiskEditFieldLabel         matlab.ui.control.Label
        VehiclePassedEditField          matlab.ui.control.NumericEditField
        VehiclePassedEditFieldLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 获取屏幕大小
            screenSize = get(0, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % 获取App窗口大小
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % 计算窗口在屏幕中央的位置
            xPosition = (screenWidth - appWidth) / 2;
            yPosition = (screenHeight - appHeight) / 2;

            % 设置窗口位置
            app.UIFigure.Position(1) = xPosition;
            app.UIFigure.Position(2) = yPosition;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.Lamp.Color='r';
            pause(0.01)

            %存储历史值，清空输出表
            app.VehiclePassedEditField_2.Value=app.VehiclePassedEditField.Value;
            app.ThroughputEditField_2.Value=app.ThroughputEditField.Value;
            app.TotalRiskEditField_2.Value=app.TotalRiskEditField.Value;
            app.AverageRiskEditField_2.Value=app.AverageRiskEditField.Value;

            app.VehiclePassedEditField.Value=0;
            app.ThroughputEditField.Value=0;
            app.TotalRiskEditField.Value=0;
            app.AverageRiskEditField.Value=0;

            %道路参数
            DL1s=app.DedicatedLane1EditField.Value;
            DL1e=app.divergingAreaEditField_3.Value;
            DL2s=app.DedicatedLane2EditField.Value;
            DL2e=app.divergingAreaEditField_5.Value;
            DL3s=app.DedicatedLane3EditField.Value;
            DL3e=app.divergingAreaEditField_7.Value;
            onrampPosition=app.OnrampPositionEditField.Value:app.onrampPositionEditField_2.Value; % 上匝道位置
            mergingArea=app.MergingAreaEditField.Value:app.mergingAreaEditField_2.Value; % 匝道汇入位置
            offrampPosition=app.OfframpPositionEditField.Value:app.offrampPositionEditField_2.Value; % 下匝道位置
            divergingArea=app.DivergingAreaEditField.Value:app.divergingAreaEditField_2.Value; % 主线离开位置
            DLsign_start=app.BufferZone_DLStartEditField.Value;
            DLsign_end=app.BufferZone_DLEndEditField.Value;

            %交通流参数
            AVMPR=app.AVMPREditField.Value;
            numVehicles=app.NumVehiclesEditField.Value;
            prob_Merge=app.Merge_RateEditField.Value;
            prob_Diverge=app.Diverge_RateEditField.Value;

            %仿真参数
            MaxIterations=app.MaxIterationsEditField.Value;
            MaxVehiclePassed=app.MaxVehiclePassedEditField.Value;
            preheatIterations=app.PreheatIterationsEditField.Value;
            if app.showButton.Value==true
                showsimulation=1;
            else
                showsimulation=0;
            end

            [VehiclePassed,Throughput,Risk_total,Risk_average]=fun_mainline_weave...
                (MaxIterations,MaxVehiclePassed,preheatIterations,AVMPR,numVehicles,onrampPosition,mergingArea,prob_Merge,offrampPosition,divergingArea,prob_Diverge,DL1s,DL1e,DL2s,DL2e,DL3s,DL3e,DLsign_start,DLsign_end,showsimulation,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3);
            app.VehiclePassedEditField.Value=VehiclePassed;
            app.ThroughputEditField.Value=Throughput;
            app.TotalRiskEditField.Value=Risk_total;
            app.AverageRiskEditField.Value=Risk_average;

            app.Lamp.Color='g';
        end

        % Button pushed function: ShowRoadButton
        function ShowRoadButtonPushed(app, event)
            onrampPosition=app.OnrampPositionEditField.Value:app.onrampPositionEditField_2.Value; % 上匝道位置
            mergingArea=app.MergingAreaEditField.Value:app.mergingAreaEditField_2.Value; % 匝道汇入位置
            offrampPosition=app.OfframpPositionEditField.Value:app.offrampPositionEditField_2.Value; % 下匝道位置
            divergingArea=app.DivergingAreaEditField.Value:app.divergingAreaEditField_2.Value; % 主线离开位置
            DL1s=app.DedicatedLane1EditField.Value;
            DL1e=app.divergingAreaEditField_3.Value;
            DL2s=app.DedicatedLane2EditField.Value;
            DL2e=app.divergingAreaEditField_5.Value;
            DL3s=app.DedicatedLane3EditField.Value;
            DL3e=app.divergingAreaEditField_7.Value;
            DLsign_start=app.BufferZone_DLStartEditField.Value;
            DLsign_end=app.BufferZone_DLEndEditField.Value;

            showroad(onrampPosition,mergingArea,offrampPosition,divergingArea,DL1s,DL1e,DL2s,DL2e,DL3s,DL3e,DLsign_start,DLsign_end,app.UIAxes_1)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RoadsettingPanel = uipanel(app.LeftPanel);
            app.RoadsettingPanel.Title = 'Road setting';
            app.OnrampPositionEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.OnrampPositionEditFieldLabel.Text = 'OnrampPosition';
            app.OnrampPositionEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.OnrampPositionEditField.Value = 200;
            app.MergingAreaEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.MergingAreaEditFieldLabel.Text = 'MergingArea';
            app.MergingAreaEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.MergingAreaEditField.Value = 300;
            app.OfframpPositionEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.OfframpPositionEditFieldLabel.Text = 'OfframpPosition';
            app.OfframpPositionEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.OfframpPositionEditField.Value = 600;
            app.DivergingAreaEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.DivergingAreaEditFieldLabel.Text = 'DivergingArea';
            app.DivergingAreaEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.DivergingAreaEditField.Value = 600;
            app.onrampPositionEditField_2 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.onrampPositionEditField_2.Value = 400;
            app.mergingAreaEditField_2 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.mergingAreaEditField_2.Value = 400;
            app.offrampPositionEditField_2 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.offrampPositionEditField_2.Value = 800;
            app.divergingAreaEditField_2 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.divergingAreaEditField_2.Value = 700;
            app.divergingAreaEditField_3 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.divergingAreaEditField_3.Value = 1000;
            app.DedicatedLane1EditFieldLabel = uilabel(app.RoadsettingPanel);
            app.DedicatedLane1EditFieldLabel.Text = 'DedicatedLane1';
            app.DedicatedLane1EditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.DedicatedLane1EditField.Value = 500;
            app.divergingAreaEditField_5 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.DedicatedLane2EditFieldLabel = uilabel(app.RoadsettingPanel);
            app.DedicatedLane2EditFieldLabel.Text = 'DedicatedLane2';
            app.DedicatedLane2EditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.divergingAreaEditField_7 = uieditfield(app.RoadsettingPanel, 'numeric');
            app.DedicatedLane3EditFieldLabel = uilabel(app.RoadsettingPanel);
            app.DedicatedLane3EditFieldLabel.Text = 'DedicatedLane3';
            app.DedicatedLane3EditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.ShowRoadButton = uibutton(app.RoadsettingPanel, 'push');
            app.ShowRoadButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRoadButtonPushed, true);
            app.ShowRoadButton.Text = 'Show Road';
            app.BufferZone_DLStartEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.BufferZone_DLStartEditFieldLabel.Text = 'BufferZone_DLStart';
            app.BufferZone_DLStartEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.BufferZone_DLStartEditField.Value = 100;
            app.BufferZone_DLEndEditFieldLabel = uilabel(app.RoadsettingPanel);
            app.BufferZone_DLEndEditFieldLabel.Text = 'BufferZone_DLEnd';
            app.BufferZone_DLEndEditField = uieditfield(app.RoadsettingPanel, 'numeric');
            app.FlowSettingsPanel = uipanel(app.LeftPanel);
            app.FlowSettingsPanel.Title = 'Flow Settings';
            app.NumVehiclesEditFieldLabel = uilabel(app.FlowSettingsPanel);
            app.NumVehiclesEditFieldLabel.Text = 'NumVehicles';
            app.NumVehiclesEditField = uieditfield(app.FlowSettingsPanel, 'numeric');
            app.NumVehiclesEditField.Value = 100;
            app.Merge_RateEditFieldLabel = uilabel(app.FlowSettingsPanel);
            app.Merge_RateEditFieldLabel.Text = 'Merge_Rate';
            app.Merge_RateEditField = uieditfield(app.FlowSettingsPanel, 'numeric');
            app.Merge_RateEditField.Value = 0.2;
            app.Diverge_RateEditFieldLabel = uilabel(app.FlowSettingsPanel);
            app.Diverge_RateEditFieldLabel.Text = 'Diverge_Rate';
            app.Diverge_RateEditField = uieditfield(app.FlowSettingsPanel, 'numeric');
            app.Diverge_RateEditField.Value = 0.2;
            app.AVMPREditFieldLabel = uilabel(app.FlowSettingsPanel);
            app.AVMPREditFieldLabel.Text = 'AVMPR';
            app.AVMPREditField = uieditfield(app.FlowSettingsPanel, 'numeric');
            app.AVMPREditField.Value = 0.5;
            app.SimulationSettingsPanel = uipanel(app.LeftPanel);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.MaxIterationsEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.MaxIterationsEditFieldLabel.Text = 'MaxIterations';
            app.MaxIterationsEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.MaxIterationsEditField.Value = 360;
            app.PreheatIterationsEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.PreheatIterationsEditFieldLabel.Text = 'PreheatIterations';
            app.PreheatIterationsEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.PreheatIterationsEditField.Value = 20;
            app.StartSimulationButton = uibutton(app.SimulationSettingsPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ButtonGroup = uibuttongroup(app.SimulationSettingsPanel);
            app.showButton = uiradiobutton(app.ButtonGroup);
            app.showButton.Text = 'show';
            app.showButton.Value = true;
            app.hideButton = uiradiobutton(app.ButtonGroup);
            app.hideButton.Text = 'hide';
            app.ShowSimLabel = uilabel(app.SimulationSettingsPanel);
            app.ShowSimLabel.Text = 'ShowSim';
            app.Lamp = uilamp(app.SimulationSettingsPanel);
            app.MaxVehiclePassedEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.MaxVehiclePassedEditFieldLabel.Text = 'MaxVehiclePassed';
            app.MaxVehiclePassedEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.MaxVehiclePassedEditField.Value = 400;
            app.RightPanel = uipanel(app.GridLayout);
            app.VisualizationPanel = uipanel(app.RightPanel);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes_1 = uiaxes(app.VisualizationPanel);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.VisualizationPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.VisualizationPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SimulationResultsPanel = uipanel(app.UIFigure);
            app.SimulationResultsPanel.Title = 'Simulation Results';
            app.VehiclePassedEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.VehiclePassedEditFieldLabel.Text = 'Vehicle Passed';
            app.VehiclePassedEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.VehiclePassedEditField.RoundFractionalValues = 'on';
            app.VehiclePassedEditField.ValueDisplayFormat = '%.0f';
            app.TotalRiskEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.TotalRiskEditFieldLabel.Text = 'Total Risk';
            app.TotalRiskEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.TotalRiskEditField.RoundFractionalValues = 'on';
            app.TotalRiskEditField.ValueDisplayFormat = '%.0f';
            app.AverageRiskEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.AverageRiskEditFieldLabel.Text = 'Average Risk';
            app.AverageRiskEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.AverageRiskEditField.RoundFractionalValues = 'on';
            app.AverageRiskEditField.ValueDisplayFormat = '%.0f';
            app.ThroughputEditFieldLabel = uilabel(app.SimulationResultsPanel);
            app.ThroughputEditFieldLabel.Text = 'Throughput';
            app.ThroughputEditField = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.ThroughputEditField.RoundFractionalValues = 'on';
            app.ThroughputEditField.ValueDisplayFormat = '%.0f';
            app.VehiclePassedEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.VehiclePassedEditField_2Label.Text = 'Vehicle Passed';
            app.VehiclePassedEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.VehiclePassedEditField_2.RoundFractionalValues = 'on';
            app.VehiclePassedEditField_2.ValueDisplayFormat = '%.0f';
            app.TotalRiskEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.TotalRiskEditField_2Label.Text = 'Total Risk';
            app.TotalRiskEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.TotalRiskEditField_2.RoundFractionalValues = 'on';
            app.TotalRiskEditField_2.ValueDisplayFormat = '%.0f';
            app.AverageRiskEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.AverageRiskEditField_2Label.Text = 'Average Risk';
            app.AverageRiskEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.AverageRiskEditField_2.RoundFractionalValues = 'on';
            app.AverageRiskEditField_2.ValueDisplayFormat = '%.0f';
            app.ThroughputEditField_2Label = uilabel(app.SimulationResultsPanel);
            app.ThroughputEditField_2Label.Text = 'Throughput';
            app.ThroughputEditField_2 = uieditfield(app.SimulationResultsPanel, 'numeric');
            app.ThroughputEditField_2.RoundFractionalValues = 'on';
            app.ThroughputEditField_2.ValueDisplayFormat = '%.0f';
            app.CurrentLabel = uilabel(app.SimulationResultsPanel);
            app.CurrentLabel.Text = 'Current：';
            app.HistoryLabel = uilabel(app.SimulationResultsPanel);
            app.HistoryLabel.Text = 'History：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExpresswayRampSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end